classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        MainTab                        matlab.ui.container.Tab
        TextArea                       matlab.ui.control.TextArea
        AnimateFlightButton            matlab.ui.control.Button
        RunningModeLabel               matlab.ui.control.Label
        RunningModeDropDown            matlab.ui.control.DropDown
        StopUDPButton                  matlab.ui.control.Button
        StartUDPButton                 matlab.ui.control.Button
        BluetoothConnectionforRealRunningLabel  matlab.ui.control.Label
        TakeOffButton                  matlab.ui.control.Button
        LandButton                     matlab.ui.control.Button
        FlightControlsLabel            matlab.ui.control.Label
        TFinalEditFieldLabel           matlab.ui.control.Label
        TFinalEditField                matlab.ui.control.NumericEditField
        StartSimulationButton          matlab.ui.control.Button
        ReplayVideoButton              matlab.ui.control.Button
        PlotDataButton                 matlab.ui.control.Button
        ControlModeDropDownLabel       matlab.ui.control.Label
        ControlModeDropDown            matlab.ui.control.DropDown
        FlightTrajectoryDropDownLabel  matlab.ui.control.Label
        FlightTrajectoryDropDown       matlab.ui.control.DropDown
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TakeOffButton
        function TakeOffButtonPushed(app, event)
            VSS_RunMode=evalin('base','VSS_RunMode');
            try
                takeOff;
                app.TextArea.Value="Taking off...";
            catch ME
                app.TextArea.Value=ME.message;
            end
        end

        % Button pushed function: LandButton
        function LandButtonPushed(app, event)
            VSS_RunMode=evalin('base','VSS_RunMode');
            try
                land;
                app.TextArea.Value="Landing...";
            catch ME
                app.TextArea.Value=ME.message;
            end
        end

        % Button pushed function: StopUDPButton
        function StopUDPButtonPushed(app, event)
            try
                stopUDP;
                app.TextArea.Value="UDP stopped";
            catch ME
                app.TextArea.Value=ME.message;
            end
        end

        % Button pushed function: StartUDPButton
        function StartUDPButtonPushed(app, event)
            try
                startUDP;
                app.TextArea.Value="UDP started";
            catch ME
                app.TextArea.Value=ME.message;
            end
        end

        % Value changed function: RunningModeDropDown
        function RunningModeDropDownValueChanged(app, event)
            value = app.RunningModeDropDown.Value;
            
            switch value
                case "Simulation"
                    app.TextArea.Value="Changed RunningMode to simulation";
                    assignin('base','VSS_RunMode',0);
                    app.StartUDPButton.Visible=false;
                    app.StopUDPButton.Visible=false;
                    app.BluetoothConnectionforRealRunningLabel.Visible=false;
                    
                case "Real Target"
                    app.TextArea.Value="Changed RunningMode to deployement on real target";
                    assignin('base','VSS_RunMode',1);
                    app.StartUDPButton.Visible=true;
                    app.StopUDPButton.Visible=true;
                    app.BluetoothConnectionforRealRunningLabel.Visible=true;
                    app.TextArea.Value=[app.TextArea.Value ; "Run the openned Simulink file, once you deployed the code on Parrot"];
                    open_system('DSSDK_TrackerSingleObject.mdl');
                    appParrot=Parrot_FlightInterface;
                    assignin("base","appParrot",appParrot);
                    dataDownloaded=false;
                    assignin("base","dataDownloaded",dataDownloaded);
            end
        end

        % Callback function: AnimateFlightButton, TextArea
        function AnimateFlightButtonPushed(app, event)
            value = app.RunningModeDropDown.Value;
            % simFinished
            switch value
                case "Simulation"
                    
                    app.TextArea.Value="animation recording started";
                    fD=evalin('base','fD');
                    pos=fD.estim.pos;
                    ang= fD.estim.ang;
                    M=animator(pos, ang);
                    assignin('base','M',M);
                    
                    app.TextArea.Value="animation recording finished";
                    
                case "Real Target"

            end
        end

        % Value changed function: TFinalEditField
        function TFinalEditFieldValueChanged(app, event)
            value = app.TFinalEditField.Value;
            
            assignin('base','TFinal',value);
        end

        % Button pushed function: StartSimulationButton
        function StartSimulationButtonPushed(app, event)
            simOut = sim('asbQuadcopter','ReturnWorkspaceOutputs','on'); % doesn't work good. Instead run simulation from Simulink
        end

        % Button pushed function: ReplayVideoButton
        function ReplayVideoButtonPushed(app, event)
            %   close gcf;
            %   app.UIAxes.Visible=true;
            %   app.TabGroup.SelectedTab = app.AnimationTab;
            %   movie(app.UIAxes,M,2); % not supported in app designer
            movieFigure=gcf;
            M=evalin('base','M');
            movie(movieFigure,M,1);
        end

        % Button pushed function: PlotDataButton
        function PlotDataButtonPushed(app, event)
            fD=evalin("base",'fD');
            plotter(fD(1),{'time','tStep','tStart','tFinal','states','sampleNum'})
        end

        % Value changed function: ControlModeDropDown
        function ControlModeDropDownValueChanged(app, event)
            value = app.ControlModeDropDown.Value;
            switch value
                case "Time Based"
                    
                    app.TextArea.Value="Changed ControlMode to Time Based";
                    assignin('base','VSS_ControlMode',0);
                    app.TakeOffButton.Visible=false;
                    app.LandButton.Visible=false;
                    app.FlightControlsLabel.Visible=false;
                    
                case "Onscreen Control"
                    app.TextArea.Value="Changed ControlMode to Onscreen Control";
                    assignin('base','VSS_ControlMode',1);
                    app.TakeOffButton.Visible=true;
                    app.LandButton.Visible=true;
                    app.FlightControlsLabel.Visible=true;
                    
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.TextArea = uitextarea(app.MainTab);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @AnimateFlightButtonPushed, true);
            app.TextArea.Editable = 'off';
            app.AnimateFlightButton = uibutton(app.MainTab, 'push');
            app.AnimateFlightButton.ButtonPushedFcn = createCallbackFcn(app, @AnimateFlightButtonPushed, true);
            app.AnimateFlightButton.Visible = 'off';
            app.AnimateFlightButton.Text = 'Animate Flight';
            app.RunningModeLabel = uilabel(app.MainTab);
            app.RunningModeLabel.Text = 'Running Mode ';
            app.RunningModeDropDown = uidropdown(app.MainTab);
            app.RunningModeDropDown.Items = {'Simulation', 'Real Target'};
            app.RunningModeDropDown.ValueChangedFcn = createCallbackFcn(app, @RunningModeDropDownValueChanged, true);
            app.RunningModeDropDown.Value = 'Simulation';
            app.StopUDPButton = uibutton(app.MainTab, 'push');
            app.StopUDPButton.ButtonPushedFcn = createCallbackFcn(app, @StopUDPButtonPushed, true);
            app.StopUDPButton.Visible = 'off';
            app.StopUDPButton.Text = 'Stop UDP';
            app.StartUDPButton = uibutton(app.MainTab, 'push');
            app.StartUDPButton.ButtonPushedFcn = createCallbackFcn(app, @StartUDPButtonPushed, true);
            app.StartUDPButton.Visible = 'off';
            app.StartUDPButton.Text = 'Start UDP';
            app.BluetoothConnectionforRealRunningLabel = uilabel(app.MainTab);
            app.BluetoothConnectionforRealRunningLabel.Visible = 'off';
            app.BluetoothConnectionforRealRunningLabel.Text = 'Bluetooth Connection for Real Running:';
            app.TakeOffButton = uibutton(app.MainTab, 'push');
            app.TakeOffButton.ButtonPushedFcn = createCallbackFcn(app, @TakeOffButtonPushed, true);
            app.TakeOffButton.Visible = 'off';
            app.TakeOffButton.Text = 'Take Off';
            app.LandButton = uibutton(app.MainTab, 'push');
            app.LandButton.ButtonPushedFcn = createCallbackFcn(app, @LandButtonPushed, true);
            app.LandButton.Visible = 'off';
            app.LandButton.Text = 'Land';
            app.FlightControlsLabel = uilabel(app.MainTab);
            app.FlightControlsLabel.Visible = 'off';
            app.FlightControlsLabel.Text = 'Flight Controls';
            app.TFinalEditFieldLabel = uilabel(app.MainTab);
            app.TFinalEditFieldLabel.Text = 'TFinal';
            app.TFinalEditField = uieditfield(app.MainTab, 'numeric');
            app.TFinalEditField.Limits = [0 Inf];
            app.TFinalEditField.ValueChangedFcn = createCallbackFcn(app, @TFinalEditFieldValueChanged, true);
            app.TFinalEditField.Value = 20;
            app.StartSimulationButton = uibutton(app.MainTab, 'push');
            app.StartSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulationButtonPushed, true);
            app.StartSimulationButton.Enable = 'off';
            app.StartSimulationButton.Visible = 'off';
            app.StartSimulationButton.Text = 'Start Simulation';
            app.ReplayVideoButton = uibutton(app.MainTab, 'push');
            app.ReplayVideoButton.ButtonPushedFcn = createCallbackFcn(app, @ReplayVideoButtonPushed, true);
            app.ReplayVideoButton.Visible = 'off';
            app.ReplayVideoButton.Text = 'Replay Video';
            app.PlotDataButton = uibutton(app.MainTab, 'push');
            app.PlotDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDataButtonPushed, true);
            app.PlotDataButton.Visible = 'off';
            app.PlotDataButton.Text = 'PlotData';
            app.ControlModeDropDownLabel = uilabel(app.MainTab);
            app.ControlModeDropDownLabel.Text = 'Control Mode';
            app.ControlModeDropDown = uidropdown(app.MainTab);
            app.ControlModeDropDown.Items = {'Time Based', 'Onscreen Control'};
            app.ControlModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ControlModeDropDownValueChanged, true);
            app.ControlModeDropDown.Value = 'Time Based';
            app.FlightTrajectoryDropDownLabel = uilabel(app.MainTab);
            app.FlightTrajectoryDropDownLabel.Text = 'Flight Trajectory';
            app.FlightTrajectoryDropDown = uidropdown(app.MainTab);
            app.FlightTrajectoryDropDown.Items = {'Hover'};
            app.FlightTrajectoryDropDown.Value = 'Hover';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end