classdef Parrot_FlightInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FlightInterface              matlab.ui.Figure
        Stop                         matlab.ui.control.Button
        secondsLabel                 matlab.ui.control.Label
        PowerGainLabel               matlab.ui.control.Label
        Slider                       matlab.ui.control.Slider
        powerGainVal                 matlab.ui.control.NumericEditField
        FlightTimeLabel              matlab.ui.control.Label
        Spinner                      matlab.ui.control.Spinner
        Start                        matlab.ui.control.StateButton
        Help                         matlab.ui.control.Button
        FlightLogButton              matlab.ui.control.Button
        initLabel                    matlab.ui.control.Label
        infoLog                      matlab.ui.control.TextArea
        log                          matlab.ui.control.TextArea
        MATFileButton                matlab.ui.control.Button
        CheckBox                     matlab.ui.control.CheckBox
        CheckBoxLabel                matlab.ui.control.Label
        percentlabel                 matlab.ui.control.Label
        keyboardControlPanel         matlab.ui.container.Panel
        KeyboardEditBox              matlab.ui.control.EditField
        EnterthekeysaboveLabel       matlab.ui.control.Label
        HelpButton                   matlab.ui.control.Button
        EnableKeyboardControlButton  matlab.ui.control.StateButton
        keyboardCheckbox             matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        p               % Instance of parrot class
        powerGain       % power-gain percent of motors
        flightDuration  % minidrone flight time
        tcpHandle % Description
        KeyboardControlActive = 0; % Description
        StartPressed = 0; % This is a flag used to check if the start was pressed already. See geck g1863793.
        oldKeyPresses = ''; % Description
        timerObj; % clock for regular callbacks
        tcpHandle_GUI % Description
        Connected = 0; % Description
        noHeartBeat % Description
    end
    
    methods (Access = public)
        
        function bringToFront(~, uiHandle)
            figure(uiHandle.FlightInterface);
        end
        
        function resizeApp(app)
            displaySizevector = get (groot, 'ScreenSize');
            bottom = displaySizevector(4) / 4;
            left = displaySizevector(3)/8;
            app.FlightInterface.Position = [left bottom 1077 448];
        end
        
        %%%%% Amin : flight finished and so download data
        function flightFinished(app)
           finishedReal(app);
        end
        
        
    end
    
    methods (Access = private)
        
        function timerCall(app, ~, event)
            app.noHeartBeat = app.noHeartBeat + 1;
            
            if(app.Connected == 1)
                try
                    if(app.noHeartBeat > 9)
                        stop(app.timerObj);
                        StopButtonPushed(app,event);
                        
                        app.log.FontColor = 'red';
                        app.log.Value = 'Unable to communicate with the drone';
                        app.Connected = 0;
                    end
                    
                    if (app.tcpHandle_GUI.BytesAvailable > 1)
                        app.noHeartBeat = 0;
                        data = read(app.tcpHandle_GUI,2);
                        if data(1) == 'B' % Drone sending 'is active' commands.
                            return; %in This case return, since there is no error.
                        elseif data(1) == 'D' % Error conditions from rsedu_control.c
                            switch data(2)
                                case 'e' % Model  gracefully stopped
                                    stop(app.timerObj);
                                    StopButtonPushed(app,event);
                                    return;
                                case 'b' % Low battery
                                    errString = 'Low battery level detected. Shutting down the drone.';
                                case 'm' % matfile logging error
                                    errString = 'Unable to allocate memory for MAT-file logging. Refer to the help topic: ‘Run on Target Hardware > Troubleshooting > Analyze Flight Log Errors';
                                otherwise
                                    % do nothing
                                    return;
                            end
                        elseif data(1) == 'F'  % Error conditions from model(parrotMinidroneHover and parrotMinidroneCompetition)
                            switch data(2)
                                case 69
                                    errString = 'Optical flow is not present for multiple cycles. Shutting down the drone. Refer to the help topic: ‘Run on Target Hardware > Troubleshooting > Analyze Flight Log Errors’.';
                                case 88
                                    errString = 'Altitude estimation error detected. Shutting down the drone.';
                                otherwise % don't show any error
                                    stop(app.timerObj);
                                    StopButtonPushed(app,event);
                                    return;
                            end
                        end
                        stop(app.timerObj);
                        StopButtonPushed(app,event);
                        
                        app.log.FontColor = 'red';
                        app.log.Value = errString;
                        app.Connected = 0;
                    end
                catch
                    % Do nothing
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.resizeApp ;
            mlock;
            persistent uiHandle;
            if ~isempty(uiHandle) && isa(uiHandle, 'Parrot_FlightInterface') && isvalid(uiHandle)
                app.bringToFront(uiHandle);
                app.delete;
            else
                uiHandle = app ;
            end
            
        end

        % Value changed function: Start
        function StartValueChanged(app, event)
            % Monitor tab controls
            if (app.StartPressed == 1) % If the start button is pressed already, discard the additional presses.
                return;
            end
            app.StartPressed = 1; % set the pressed flag, in order to discard multiple Start presses.
            app.Start.Enable = 'off';
            app.Slider.Enable = 'off';
            app.Spinner.Enable = 'off';
            app.log.Enable = 'on';
            app.log.Visible = 'on';
            app.log.FontColor = [0.47,0.67,0.19];
            app.log.Value = 'Preparing for Take-off . . .';
            app.FlightLogButton.Enable = 'off';
            app.MATFileButton.Enable = 'off';
            
            drawnow;
            app.powerGain = app.Slider.Value;
            if ~isempty(app.p) && isa(app.p, 'codertarget.parrot.internal.parrotio')
                app.p.delete;
            end
            try
                app.p = codertarget.parrot.internal.parrotio; % Start command sent to server
                app.p.PowerGain = app.powerGain/100.0 ;
                app.log.FontColor = [0.47,0.67,0.19];
                app.log.Value = ' Preparing for Take-off . . .';
                drawnow;
                
                % make power-gain and flightduration invisible
                app.PowerGainLabel.Visible = 'off';
                app.Slider.Visible = 'off';
                app.powerGainVal.Visible = 'off';
                app.percentlabel.Visible = 'off';
                app.FlightTimeLabel.Visible = 'off';
                app.Spinner.Visible = 'off';
                app.secondsLabel.Visible = 'off';
                app.initLabel.Visible = 'off';
                app.CheckBox.Visible = 'off';
                app.CheckBoxLabel.Visible = 'off';
                
                app.log.Visible = 'on';
                app.infoLog.Visible = 'on';
                app.infoLog.Enable = 'on';
                
                % make the panel for receiving artifacts from drone visible
                app.FlightLogButton.Visible = 'off';
                app.MATFileButton.Visible = 'off';
                app.Start.Visible = 'off';
                drawnow;
                app.Stop.Enable = 'on';
                app.Stop.Visible = 'on';
                drawnow;
                
                if app.CheckBox.Value == 1
                    app.infoLog.Value = [' Power-Gain : ' num2str(uint8(app.powerGain)) '%' newline ' Flight Duration : Simulation Stop Time'];
                    app.p.takeoff('nonblocking') ; % TakeOff command sent to server. Flight time is the Simulation time in model
                else
                    app.flightDuration = app.Spinner.Value;
                    app.infoLog.Value = [' Power-Gain : ' num2str(uint8(app.powerGain)) '%' newline ' Flight Duration : ' num2str(app.flightDuration) ' seconds'];
                    app.p.FlightDuration = app.flightDuration;
                    app.p.takeoff('nonblocking') ; % TakeOff command sent to server
                end
                
                app.log.Value = 'Minidrone Flight Started . . .';
                
                app.keyboardCheckbox.Visible = 'on';
                if(app.keyboardCheckbox.Value == 1)
                    app.keyboardControlPanel.Visible = 'on';
                else
                    app.keyboardControlPanel.Visible = 'off';
                end
                
                
                if isempty(app.timerObj)
                    app.timerObj = timer;
                end
                if(isequal(app.timerObj.Running, 'off'))
                    try
                        app.tcpHandle_GUI = tcpclient(app.p.IPAddress,24099);
                        app.Connected = 1;
                        
                        app.noHeartBeat = 0;
                        app.timerObj.Period = 1;
                        app.timerObj.ExecutionMode = 'fixedRate';
                        app.timerObj.TimerFcn = @app.timerCall;
                        start(app.timerObj);
                    catch
                        %                         disp('Unable to connect to the drone monitor');
                    end
                end
                
            catch ME
                app.StartPressed = 0; % Since the model didn't start, allow more clicks.
                app.log.FontColor = [1,0,0];
                app.log.Value = ME.message;
                drawnow;
                
                app.Start.Visible = 'on';
                app.Stop.Visible = 'off';
                app.Stop.Enable = 'off';
                
                % make power-gain and flightduration visible
                app.PowerGainLabel.Visible = 'on';
                app.Slider.Visible = 'on';
                app.powerGainVal.Visible = 'on';
                app.percentlabel.Visible = 'on';
                app.initLabel.Visible = 'on';
                app.CheckBox.Visible = 'on';
                app.CheckBoxLabel.Visible = 'on';
                app.CheckBoxValueChanged(event);
                
                app.log.Enable = 'on';
                app.infoLog.Visible = 'off';
                if ~isempty(app.p) && isa(app.p, 'parrot')
                    app.FlightLogButton.Visible = 'on';
                    app.MATFileButton.Visible = 'on';
                    
                    %%%%
                    app.flightFinished;
                end
            end
            app.Start.Enable = 'on';
            app.Slider.Enable = 'on';
            app.Spinner.Enable = 'on';
            app.MATFileButton.Enable = 'on';
            app.FlightLogButton.Enable = 'on';
            
            %%%%
            app.flightFinished;
            
            
        end

        % Button pushed function: Stop
        function StopButtonPushed(app, event)
            app.StartPressed = 0; % Clear the flag for 'Start' button pressed.
            try
                app.p.abort;
                app.log.FontColor = [0.47,0.67,0.19];
                app.log.Value = 'Flight Completed . . .';
            catch ME
                app.log.FontColor = [1,0,0];
                app.log.Value = ME.message;
            end
            
            app.Start.Visible = 'on';
            app.Stop.Visible = 'off';
            app.Stop.Enable = 'off';
            
            % make power-gain and flightduration visible
            app.PowerGainLabel.Visible = 'on';
            app.Slider.Visible = 'on';
            app.powerGainVal.Visible = 'on';
            app.percentlabel.Visible = 'on';
            app.initLabel.Visible = 'on';
            app.CheckBox.Visible = 'on';
            app.CheckBoxLabel.Visible = 'on';
            app.CheckBoxValueChanged(event);
            
            
            % make the panel for receiving artifacts from drone visible
            app.FlightLogButton.Visible = 'on';
            app.FlightLogButton.Enable = 'on';
            app.MATFileButton.Visible = 'on';
            app.MATFileButton.Enable = 'on';
            
            
            drawnow;
            app.log.Enable = 'on';
            app.infoLog.Visible = 'off';
            
            app.keyboardCheckbox.Visible = 'off';
            app.keyboardControlPanel.Visible = 'off';
            app.KeyboardEditBox.Value = 'Keyboard input inactive...';
            if(app.KeyboardControlActive)
                %set the button to reset state
                app.EnableKeyboardControlButton.Value = 0;
                %change the color to default
                app.EnableKeyboardControlButton.BackgroundColor = [0.71,0.99,0.62]; %light tinge of green
                %disable keyboard input panel
                app.KeyboardEditBox.Enable = 'off';
                %change text inside editbox
                app.KeyboardEditBox.Value = 'Keyboard input inactive...';
                %clear the contents of app.oldKeyPresses
                app.oldKeyPresses = '';
                %change font color to black
                app.EnableKeyboardControlButton.FontColor = [0 0 0];
                %change text to stop keyboard control
                app.EnableKeyboardControlButton.Text = 'Enable keyboard control';
                %set enabled flag to 0
                app.KeyboardControlActive = 0;
            end
            stop(app.timerObj);
            
            %%%%
            app.flightFinished;
        end

        % Button pushed function: Help
        function HelpButtonPushed(app, event)
            % Open the PARROT minidrone support package documentation
            helpview(fullfile(codertarget.internal.parrot.getDocRoot,'ug','use-flightcontrol-ui-on-parrot-minidrone.html'),'-helpbrowser');
        end

        % Button pushed function: FlightLogButton
        function FlightLogButtonPushed(app, event)
            app.log.FontColor = [0.47,0.67,0.19];
            app.log.Value = 'Downloading Flight-log . . .';
            drawnow;
            try
                app.p.getFlightLog;
                app.log.Value = 'Flight-log downloaded to current directory!';
            catch ME
                app.log.FontColor = [1,0,0];
                app.log.Value = ME.message;
                drawnow;
            end
        end

        % Button pushed function: MATFileButton
        function MATFileButtonPushed(app, event)
            app.log.FontColor = [0.47,0.67,0.19];
            app.log.Value = 'Downloading MAT-File . . .';
            drawnow;
            try
                app.p.getMATFile;
                app.log.Value = 'MAT-File downloaded to current directory!';
            catch
                app.log.FontColor = [1,0,0];
                app.log.Value = 'MAT-File not found on the minidrone. Ensure that MAT-File logging is enabled and build the model again. Note that MAT-File logging is only available if you have Simulink Coder.';
                drawnow;
            end
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            if (value == 1)
                app.FlightTimeLabel.Visible = 'off';
                app.Spinner.Visible = 'off';
                app.secondsLabel.Visible = 'off';
            else
                app.FlightTimeLabel.Visible = 'on';
                app.Spinner.Visible = 'on';
                app.secondsLabel.Visible = 'on';
            end
            drawnow;
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.powerGainVal.Value = value;
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            app.powerGainVal.Value = changingValue;
        end

        % Close request function: FlightInterface
        function FlightInterfaceCloseRequest(app, event)
            if ~isempty(app.timerObj)
                stop(app.timerObj);
                delete(app.timerObj);
            end
            delete(app);
            munlock;
            clear uiHandle;
        end

        % Value changed function: keyboardCheckbox
        function keyboardCheckboxValueChanged(app, event)
            value = app.keyboardCheckbox.Value;
            if value == 1
                app.keyboardControlPanel.Visible = 'on';
            else
                %make the panel invisble
                app.keyboardControlPanel.Visible = 'off';
            end
        end

        % Value changing function: KeyboardEditBox
        function KeyboardEditBoxValueChanging(app, event)
            changingValue = event.Value; %get the new string
            if (length(app.oldKeyPresses) >= length(changingValue)) %check if the length of the new string is more than than the old one
                app.oldKeyPresses = changingValue; %if the new string is smaller (in case the user deletes a character ), just copy the string to the buffer and return
                return; % if the user selects the entire string, the new String ==  old String
            else
                padding = zeros(1, length(changingValue) - length(app.oldKeyPresses)); %check the string length difference to add padding to the old string.
                diff = [app.oldKeyPresses padding] - changingValue; % new string = 'abcd', old string = 'abc', padded string = ['abc' 0], diff = [0 0 0 -94]
                indices = find(diff); %find the index of the first difference, which is the new character pressed.
            end
            app.oldKeyPresses = changingValue; % copy the new string to buffer for the next comparison
            charToSend = changingValue(indices(1)); %get the index of the first change. This is where the new character is pressed
            %clear the editbox
            try
                write(app.tcpHandle, uint8(charToSend)); %send the new character via TCP
            catch
                app.log.FontColor = 'red';
                app.log.Value = 'Failed to transfer keyboard character to drone. Check if Simulink model is running in drone.';
            end
            
        end

        % Value changed function: EnableKeyboardControlButton
        function EnableKeyboardControlButtonValueChanged(app, event)
            value = app.EnableKeyboardControlButton.Value;
            if (value == 1)
                try
                    %start tcp + check errors
                    app.tcpHandle = tcpclient(app.p.IPAddress,26061);
                    %enable keyboard input panel
                    app.KeyboardEditBox.Enable = 'on';
                    %change text inside editbox
                    app.KeyboardEditBox.Value = '';%'Keyboard Control Active...'
                    %change font color to black
                    app.EnableKeyboardControlButton.FontColor = [1 1 1];
                    %change text to stop keyboard control
                    app.EnableKeyboardControlButton.Text = 'Disable keyboard control';
                    %change the color to red to show disable
                    app.EnableKeyboardControlButton.BackgroundColor = [1.0,0.3,0.3]; %light tinge of red
                    %set enabled flag
                    app.KeyboardControlActive = 1;
                catch
                    app.log.FontColor = 'red';
                    app.log.Value = 'Simulink model that includes a Keyboard Read block must be running in drone to enable Keyboard Control.';
                end
            else
                %disable keyboard input panel
                app.KeyboardEditBox.Enable = 'off';
                %change text inside editbox
                app.KeyboardEditBox.Value = 'Keyboard input inactive...';
                %change font color to black
                app.EnableKeyboardControlButton.FontColor = [0 0 0];
                %change text to stop keyboard control
                app.EnableKeyboardControlButton.Text = 'Enable keyboard control';
                %clear the contents of app.oldKeyPresses
                app.oldKeyPresses = '';
                %change the color to green
                app.EnableKeyboardControlButton.BackgroundColor = [0.71,0.99,0.62]; %light tinge of green
                %set enabled flag to 0
                app.KeyboardControlActive = 0;
            end
            
        end

        % Value changed function: log
        function logValueChanged(app, event)
            value = app.log.Value;
            
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed2(app, event)
            % Show the keyboard control page.
            helpview(fullfile(codertarget.internal.parrot.getDocRoot,'ug','keyboard-control-of-drone.html'),'-helpbrowser');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FlightInterface = uifigure('Visible', 'off');
            app.FlightInterface.Name = 'Parrot Flight Control Interface';
            app.FlightInterface.CloseRequestFcn = createCallbackFcn(app, @FlightInterfaceCloseRequest, true);
            app.Stop = uibutton(app.FlightInterface, 'push');
            app.Stop.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.Stop.Enable = 'off';
            app.Stop.Visible = 'off';
            app.Stop.Text = 'STOP';
            app.secondsLabel = uilabel(app.FlightInterface);
            app.secondsLabel.Visible = 'off';
            app.secondsLabel.Text = 'Seconds';
            app.PowerGainLabel = uilabel(app.FlightInterface);
            app.PowerGainLabel.Text = 'PowerGain';
            app.Slider = uislider(app.FlightInterface);
            app.Slider.MajorTicks = [0 20 40 60 80 100];
            app.Slider.MajorTickLabels = {'1', '20', '40', '60', '80', '100'};
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [];
            app.Slider.Value = 100;
            app.powerGainVal = uieditfield(app.FlightInterface, 'numeric');
            app.powerGainVal.ValueDisplayFormat = '%.0f';
            app.powerGainVal.Editable = 'off';
            app.powerGainVal.Value = 100;
            app.FlightTimeLabel = uilabel(app.FlightInterface);
            app.FlightTimeLabel.Visible = 'off';
            app.FlightTimeLabel.Text = 'Flight Time';
            app.Spinner = uispinner(app.FlightInterface);
            app.Spinner.Step = 5;
            app.Spinner.Limits = [5 180];
            app.Spinner.ValueDisplayFormat = '%.0f';
            app.Spinner.Visible = 'off';
            app.Spinner.Value = 20;
            app.Start = uibutton(app.FlightInterface, 'state');
            app.Start.ValueChangedFcn = createCallbackFcn(app, @StartValueChanged, true);
            app.Start.Text = 'START';
            app.Help = uibutton(app.FlightInterface, 'push');
            app.Help.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.Help.Icon = 'help_64.png';
            app.Help.Text = '';
            app.FlightLogButton = uibutton(app.FlightInterface, 'push');
            app.FlightLogButton.ButtonPushedFcn = createCallbackFcn(app, @FlightLogButtonPushed, true);
            app.FlightLogButton.Enable = 'off';
            app.FlightLogButton.Visible = 'off';
            app.FlightLogButton.Text = 'Flight Log';
            app.initLabel = uilabel(app.FlightInterface);
            app.initLabel.Text = 'Set Power Gain and Flight Time for the Minidrone';
            app.infoLog = uitextarea(app.FlightInterface);
            app.infoLog.Editable = 'off';
            app.infoLog.Enable = 'off';
            app.infoLog.Visible = 'off';
            app.log = uitextarea(app.FlightInterface);
            app.log.ValueChangedFcn = createCallbackFcn(app, @logValueChanged, true);
            app.log.Editable = 'off';
            app.log.Enable = 'off';
            app.log.Visible = 'off';
            app.MATFileButton = uibutton(app.FlightInterface, 'push');
            app.MATFileButton.ButtonPushedFcn = createCallbackFcn(app, @MATFileButtonPushed, true);
            app.MATFileButton.Enable = 'off';
            app.MATFileButton.Visible = 'off';
            app.MATFileButton.Text = 'MAT File';
            app.CheckBox = uicheckbox(app.FlightInterface);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.CheckBox.Value = true;
            app.CheckBoxLabel = uilabel(app.FlightInterface);
            app.CheckBoxLabel.Text = 'Use Simulation Stop Time as Flight Duration';
            app.percentlabel = uilabel(app.FlightInterface);
            app.percentlabel.Text = '%';
            app.keyboardControlPanel = uipanel(app.FlightInterface);
            app.keyboardControlPanel.Visible = 'off';
            app.KeyboardEditBox = uieditfield(app.keyboardControlPanel, 'text');
            app.KeyboardEditBox.ValueChangingFcn = createCallbackFcn(app, @KeyboardEditBoxValueChanging, true);
            app.KeyboardEditBox.Enable = 'off';
            app.KeyboardEditBox.Value = 'Keyboard input inactive';
            app.EnterthekeysaboveLabel = uilabel(app.keyboardControlPanel);
            app.EnterthekeysaboveLabel.Text = {'Enter the keys '; 'above'};
            app.HelpButton = uibutton(app.keyboardControlPanel, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed2, true);
            app.HelpButton.Text = 'Help';
            app.EnableKeyboardControlButton = uibutton(app.keyboardControlPanel, 'state');
            app.EnableKeyboardControlButton.ValueChangedFcn = createCallbackFcn(app, @EnableKeyboardControlButtonValueChanged, true);
            app.EnableKeyboardControlButton.Text = 'Enable Keyboard Control';
            app.keyboardCheckbox = uicheckbox(app.FlightInterface);
            app.keyboardCheckbox.ValueChangedFcn = createCallbackFcn(app, @keyboardCheckboxValueChanged, true);
            app.keyboardCheckbox.Visible = 'off';
            app.keyboardCheckbox.Text = 'Show Keyboard Control Panel';
            % Show the figure after all components are created
            app.FlightInterface.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Parrot_FlightInterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FlightInterface)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FlightInterface)
        end
    end
end